classdef BISECCION < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        txtNoIteraciones               matlab.ui.control.NumericEditField
        NoIteracionesEditFieldLabel    matlab.ui.control.Label
        txtErrorEstimado               matlab.ui.control.NumericEditField
        ErrorestimadoEditFieldLabel    matlab.ui.control.Label
        txtFormulaIngresada            matlab.ui.control.EditField
        IngreselafrmulaEditFieldLabel  matlab.ui.control.Label
        btnCalcular                    matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        VolveralmenButton              matlab.ui.control.Button
        METODODELABISECCIONLabel       matlab.ui.control.Label
        UIGrafica                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: VolveralmenButton
        function VolveralmenButtonPushed(app, event)
            MenuPrincipal;
            close(app.UIFigure);
        end

        % Button pushed function: btnCalcular
        function btnCalcularButtonPushed(app, event)
            %1. Obtener la función que ingresa el Usuario
            func_str=app.txtFormulaIngresada.Value;
            try
                %Convertimos a función numérica
                f=str2func(['@(x)' func_str]);
                %Busqueda del intervalo para obtener [xa, xb]
                %Se busca en un rango de -100 a 100 de 0.5 en 0.5
                xa=nan; xb=nan;
                rango=-100:0.5:100;
                encontrado = false;

                for i=1:length(rango)-1
                    if f(rango(i))*f(rango(i+1))<0
                        xa = rango(i);
                        xb=rango(i+1);
                        encontrado = true;
                        break; %Se detiene al encontrar el primer intervalo
              
                    end
                end

                if~encontrado
                    uialert(app.UIFigure, 'No se encontró un intervalo con raíz en el rango buscado')
                    return;
                end
                %3.Parametros del método
                es=app.txtErrorEstimado.Value; %Error deseado
                max_iter=app.txtNoIteraciones.Value;
                iter=0;
                ea=100;
                xr=0;
                xr_old=0;

                %Datos para la tabla, matriz de resultados
                resultados=[];

                %Ciclo de Bisección
                while ea > es && iter<max_iter
                    iter=iter+1;
                    xr=(xa+xb)/2; %Punto medio
                    if iter>1
                        ea=abs((xr-xr_old)/xr)*100;
                    else
                        ea=100;%error inicial
                    end
                    %Calcular el producto de prueba para saber a donde ir
                    test_prod=f(xa)*f(xr);

                    %Guardamos datos en la MATRIZ
                    %COLUMNAS: Xi, Xu, Xr, F(xi), F(xr), F(xi*F(xr)
                    resultados(iter, 1)=xa;
                    resultados(iter, 2)=xb;
                    resultados(iter, 3)=xr;
                    resultados(iter, 4)=f(xa);
                    resultados(iter, 5)=f(xr);
                    resultados(iter, 6)=test_prod; %F(xi)*F(xr)

                    if test_prod<0
                        xb=xr; %la raíz esta a la izquierda
                    else if test_prod>0
                            xa=xr;
                    else
                        ea=0;
                    end
                    xr_old = xr;
                    end
                    
                end
                app.UITable.Data=num2cell(resultados);
            fplot(app.UIGrafica, f,[-2,2]);
            % 6. GRAFICAR
        cla(app.UIGrafica); % Limpia la gráfica anterior
        fplot(app.UIGrafica, f, [xr-5, xr+5]); % Grafica alrededor de la raíz
        grid(app.UIGrafica, 'on');
        hold(app.UIGrafica, 'on');
        %Marcamos donde la función cruza el cero en el eje y
        yline(app.UIGrafica, 0, '--g', 'Alpha',0.5);
        plot(app.UIGrafica, xr, f(xr), 'ro', 'MarkerSize', 10, 'LineWidth', 2, 'MarkerFaceColor', 'r'); % Punto de la raíz
        legend(app.UIGrafica, {'f(x)', 'Eje x', 'Raíz encontrada'},'Location','northeast');
        grid(app.UIGrafica,'on');
        hold(app.UIGrafica, 'off');

    catch ME
        uialert(app.UIFigure, ['Error en la función o el cálculo: ' ME.message], 'Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIGrafica = uiaxes(app.UIFigure);
            title(app.UIGrafica, 'Gráfica')
            xlabel(app.UIGrafica, 'X')
            ylabel(app.UIGrafica, 'Y')
            zlabel(app.UIGrafica, 'Z')
            app.UIGrafica.XTickLabel = {'-2'; '-1'; '0.4'; '0.6'; '0.8'; '1'};
            app.METODODELABISECCIONLabel = uilabel(app.UIFigure);
            app.METODODELABISECCIONLabel.Text = 'METODO DE LA BISECCION';
            app.VolveralmenButton = uibutton(app.UIFigure, 'push');
            app.VolveralmenButton.ButtonPushedFcn = createCallbackFcn(app, @VolveralmenButtonPushed, true);
            app.VolveralmenButton.Text = 'Volver al menú';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Xi'; 'Xu'; 'Xr'; 'F(xi)'; 'F(xr)'; 'F(Xi)*F(Xr)'};
            app.UITable.RowName = {};
            app.btnCalcular = uibutton(app.UIFigure, 'push');
            app.btnCalcular.ButtonPushedFcn = createCallbackFcn(app, @btnCalcularButtonPushed, true);
            app.btnCalcular.Text = 'CALCULAR';
            app.IngreselafrmulaEditFieldLabel = uilabel(app.UIFigure);
            app.IngreselafrmulaEditFieldLabel.Text = 'Ingrese la fórmula:';
            app.txtFormulaIngresada = uieditfield(app.UIFigure, 'text');
            app.txtFormulaIngresada.Value = 'x^2-2';
            app.ErrorestimadoEditFieldLabel = uilabel(app.UIFigure);
            app.ErrorestimadoEditFieldLabel.Text = 'Error estimado:';
            app.txtErrorEstimado = uieditfield(app.UIFigure, 'numeric');
            app.txtErrorEstimado.Value = 0.001;
            app.NoIteracionesEditFieldLabel = uilabel(app.UIFigure);
            app.NoIteracionesEditFieldLabel.Text = 'No. Iteraciones:';
            app.txtNoIteraciones = uieditfield(app.UIFigure, 'numeric');
            app.txtNoIteraciones.Value = 20;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BISECCION

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end