classdef MULLER < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        txtIteraciones                 matlab.ui.control.NumericEditField
        NoiteracionesLabel             matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        txtErrorEstimado               matlab.ui.control.NumericEditField
        ErrorestimadoEditFieldLabel    matlab.ui.control.Label
        txtx2                          matlab.ui.control.NumericEditField
        X1Label_2                      matlab.ui.control.Label
        txtx1                          matlab.ui.control.NumericEditField
        X1Label                        matlab.ui.control.Label
        txtx0                          matlab.ui.control.NumericEditField
        X0EditFieldLabel               matlab.ui.control.Label
        txtFormulaIngresada            matlab.ui.control.EditField
        IngreselafrmulaEditFieldLabel  matlab.ui.control.Label
        VolveralmenButton              matlab.ui.control.Button
        btnCalcular                    matlab.ui.control.Button
        METODODEMULLERLabel            matlab.ui.control.Label
        UIGrafica                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnCalcular
        function btnCalcularButtonPushed(app, event)
            %Obtenemos los datos que ingresa el usuario
            func_str = app.txtFormulaIngresada.Value;
            x0 = app.txtx0.Value;
            x1 = app.txtx1.Value;
            x2 = app.txtx2.Value;
            es = app.txtErrorEstimado.Value;
            max_iter = app.txtIteraciones.Value;

            try
        % 2. Convertir a función numérica
        f = str2func(['@(x)' func_str]);
        
        iter = 0;
        ea = 100;
        resultados = [];
        
        % 3. CICLO DE MÜLLER
        while ea > es && iter < max_iter
            iter = iter + 1;
            
            % Evaluamos la función en los 3 puntos
            f0 = f(x0); f1 = f(x1); f2 = f(x2);
            
            % Diferencias para construir la parábola
            h0 = x1 - x0;      h1 = x2 - x1;
            d0 = (f1 - f0)/h0; d1 = (f2 - f1)/h1;
            
            % Coeficientes de la parábola: a*x^2 + b*x + c
            a = (d1 - d0) / (h1 + h0);
            b = a*h1 + d1;
            c = f2;
            
            % Cálculo del discriminante (para elegir el signo que evite división por cero)
            discr = sqrt(b^2 - 4*a*c);
            
            if abs(b + discr) > abs(b - discr)
                den = b + discr;
            else
                den = b - discr;
            end
            
            % Nueva aproximación (x3)
            dx = -2*c / den;
            x3 = x2 + dx;
            
            % Calcular error estimado
            if x3 ~= 0
                ea = abs(dx / x3) * 100;
            end
            
            % Guardar datos: [Iter, x0, x1, x2, x3, Error]
            resultados(iter, :) = [iter, x0, x1, x2, real(x3), ea];
            
            % Actualizar puntos para la siguiente iteración
            x0 = x1;
            x1 = x2;
            x2 = x3;
        end

        % 4. MOSTRAR EN TABLA (Solo la parte real por si salen raíces complejas)
        app.UITable.Data = num2cell(real(resultados));
        app.UITable.ColumnName = {'Iter', 'x0', 'x1', 'x2', 'Raíz (x3)', 'Error %'};

        % 5. GRAFICAR
        cla(app.UIGrafica);
        hold(app.UIGrafica, 'on');
        
        % Graficar la función f(x)
        raiz_final = real(x2);
        fplot(app.UIGrafica, f, [raiz_final-5, raiz_final+5], 'LineWidth', 1.5);
        
        % Línea en el eje X
        yline(app.UIGrafica, 0, '--k', 'HandleVisibility', 'off');
        
        % Punto de la raíz
        plot(app.UIGrafica, raiz_final, f(raiz_final), 'ro', 'MarkerSize', 10, 'LineWidth', 2);
        
        legend(app.UIGrafica, 'f(x)', 'Raíz Müller');
        grid(app.UIGrafica, 'on');
        hold(app.UIGrafica, 'off');

    catch ME
        uialert(app.UIFigure, ['Error en el cálculo: ' ME.message], 'Error');
	end
            
        end

        % Button pushed function: VolveralmenButton
        function VolveralmenButtonPushed(app, event)
            MenuPrincipal;
            close(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIGrafica = uiaxes(app.UIFigure);
            title(app.UIGrafica, 'Gráfica')
            xlabel(app.UIGrafica, 'X')
            ylabel(app.UIGrafica, 'Y')
            zlabel(app.UIGrafica, 'Z')
            app.METODODEMULLERLabel = uilabel(app.UIFigure);
            app.METODODEMULLERLabel.Text = 'METODO DE MULLER';
            app.btnCalcular = uibutton(app.UIFigure, 'push');
            app.btnCalcular.ButtonPushedFcn = createCallbackFcn(app, @btnCalcularButtonPushed, true);
            app.btnCalcular.Text = 'CALCULAR';
            app.VolveralmenButton = uibutton(app.UIFigure, 'push');
            app.VolveralmenButton.ButtonPushedFcn = createCallbackFcn(app, @VolveralmenButtonPushed, true);
            app.VolveralmenButton.Text = 'Volver al menú';
            app.IngreselafrmulaEditFieldLabel = uilabel(app.UIFigure);
            app.IngreselafrmulaEditFieldLabel.Text = 'Ingrese la fórmula:';
            app.txtFormulaIngresada = uieditfield(app.UIFigure, 'text');
            app.txtFormulaIngresada.Value = 'x^3-13*x-12';
            app.X0EditFieldLabel = uilabel(app.UIFigure);
            app.X0EditFieldLabel.Text = 'X0';
            app.txtx0 = uieditfield(app.UIFigure, 'numeric');
            app.txtx0.ValueDisplayFormat = '%.2f';
            app.txtx0.Value = 4.5;
            app.X1Label = uilabel(app.UIFigure);
            app.X1Label.Text = 'X1';
            app.txtx1 = uieditfield(app.UIFigure, 'numeric');
            app.txtx1.ValueDisplayFormat = '%.2f';
            app.txtx1.Value = 4.8;
            app.X1Label_2 = uilabel(app.UIFigure);
            app.X1Label_2.Text = 'X2';
            app.txtx2 = uieditfield(app.UIFigure, 'numeric');
            app.txtx2.ValueDisplayFormat = '%.2f';
            app.txtx2.Value = 5;
            app.ErrorestimadoEditFieldLabel = uilabel(app.UIFigure);
            app.ErrorestimadoEditFieldLabel.Text = 'Error estimado:';
            app.txtErrorEstimado = uieditfield(app.UIFigure, 'numeric');
            app.txtErrorEstimado.Value = 0.001;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Iter'; 'x0'; 'x1'; 'x2'; 'Raíz (x3)'; 'Error %'};
            app.UITable.RowName = {};
            app.NoiteracionesLabel = uilabel(app.UIFigure);
            app.NoiteracionesLabel.Text = 'Max. iteraciones';
            app.txtIteraciones = uieditfield(app.UIFigure, 'numeric');
            app.txtIteraciones.ValueDisplayFormat = '%.0f';
            app.txtIteraciones.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MULLER

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end