classdef NEWTONRAPHSON < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        VolveralmenButton     matlab.ui.control.Button
        btnCalcular           matlab.ui.control.Button
        Respuesta             matlab.ui.control.Table
        txtErrorEst           matlab.ui.control.NumericEditField
        ErrorestimadoLabel    matlab.ui.control.Label
        txtIteraciones        matlab.ui.control.NumericEditField
        NoiteracionesLabel    matlab.ui.control.Label
        txtx0                 matlab.ui.control.NumericEditField
        X0EditFieldLabel      matlab.ui.control.Label
        txtFuncionIngresada   matlab.ui.control.EditField
        FuncinEditFieldLabel  matlab.ui.control.Label
        TituloVentana         matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnCalcular
        function btnCalcularButtonPushed(app, event)
            %Logica del boton Calcular
            funcion=app.txtFuncionIngresada.Value;
            xi=app.txtx0.Value;
            iter=app.txtIteraciones.Value;
            es=app.txtErrorEst.Value;

            fs=str2sym(funcion); %Convertir de string a variable
            Vs=symvar(fs);
            dfs=diff(fs, Vs, 1); %Calcular la derivada de la funcion
            for i=1: iter+1
                xi_2(i)=double (xi(i)-subs(fs,Vs,xi(i)))/subs(dfs,Vs,xi(i));
                xi(i+1)=xi_2(i);
                Fxi(i)=double(subs(fs, Vs, xi(i)));
                ea(i)=abs((xi_2(i)-xi(i))/xi_2(i))*100;
                if ea(i)<es
                    break;
                end
            end
            %Valores que va arrojando la iteración
            M2=num2cell([double(xi(1:end-1))',double(Fxi)',double(ea')]);
            app.Respuesta.Data=M2;
            fplot(app.UIAxes,funcion,[-2,2]);

        end

        % Value changed function: txtErrorEst
        function MetodoDropDownValueChanged(app, event)

            
        end

        % Button pushed function: VolveralmenButton
        function VolveralmenButtonPushed(app, event)
            MenuPrincipal;
            close (app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Gráfica')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TituloVentana = uilabel(app.UIFigure);
            app.TituloVentana.Text = 'METODO NEWTON - RAPHSON';
            app.FuncinEditFieldLabel = uilabel(app.UIFigure);
            app.FuncinEditFieldLabel.Text = 'Función:';
            app.txtFuncionIngresada = uieditfield(app.UIFigure, 'text');
            app.txtFuncionIngresada.Value = 'cos(x)+x';
            app.X0EditFieldLabel = uilabel(app.UIFigure);
            app.X0EditFieldLabel.Text = 'X0';
            app.txtx0 = uieditfield(app.UIFigure, 'numeric');
            app.txtx0.ValueDisplayFormat = '%.0f';
            app.NoiteracionesLabel = uilabel(app.UIFigure);
            app.NoiteracionesLabel.Text = 'No. iteraciones';
            app.txtIteraciones = uieditfield(app.UIFigure, 'numeric');
            app.txtIteraciones.ValueDisplayFormat = '%.0f';
            app.txtIteraciones.Value = 20;
            app.ErrorestimadoLabel = uilabel(app.UIFigure);
            app.ErrorestimadoLabel.Text = 'Error estimado:';
            app.txtErrorEst = uieditfield(app.UIFigure, 'numeric');
            app.txtErrorEst.ValueDisplayFormat = '%.4f';
            app.txtErrorEst.ValueChangedFcn = createCallbackFcn(app, @MetodoDropDownValueChanged, true);
            app.txtErrorEst.Value = 0.0001;
            app.Respuesta = uitable(app.UIFigure);
            app.Respuesta.ColumnName = {'Xi'; 'f(xi)'; 'Error relativo (%)'};
            app.Respuesta.RowName = {};
            app.btnCalcular = uibutton(app.UIFigure, 'push');
            app.btnCalcular.ButtonPushedFcn = createCallbackFcn(app, @btnCalcularButtonPushed, true);
            app.btnCalcular.Text = 'CALCULAR';
            app.VolveralmenButton = uibutton(app.UIFigure, 'push');
            app.VolveralmenButton.ButtonPushedFcn = createCallbackFcn(app, @VolveralmenButtonPushed, true);
            app.VolveralmenButton.Text = 'Volver al menú';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NEWTONRAPHSON

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end