classdef NRMODIFICADO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UITable                        matlab.ui.control.Table
        txtIteraciones                 matlab.ui.control.NumericEditField
        NoiteracionesLabel             matlab.ui.control.Label
        txtErrorEstimado               matlab.ui.control.NumericEditField
        ErrorestimadoEditFieldLabel    matlab.ui.control.Label
        txtx0                          matlab.ui.control.NumericEditField
        X0EditFieldLabel               matlab.ui.control.Label
        VolveralmenButton              matlab.ui.control.Button
        btnCalcular                    matlab.ui.control.Button
        txtFormulaIngresada            matlab.ui.control.EditField
        IngreselafrmulaEditFieldLabel  matlab.ui.control.Label
        METODONEWTONRAPHSONMODIFICADOLabel  matlab.ui.control.Label
        UIGrafica                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: VolveralmenButton
        function VolveralmenButtonPushed(app, event)
            MenuPrincipal;
            close.(app.UIFigure);
        end

        % Button pushed function: btnCalcular
        function btnCalcularButtonPushed(app, event)
            %Obtenemos datos ingresador por el usuario
            func_str=app.txtFormulaIngresada.Value;
            x0=app.txtx0.Value;
            es=app.txtErrorEstimado.Value;
            max_iter=app.txtIteraciones.Value;
            try
        % 2. CALCULAR DERIVADAS SIMBÓLICAS
        syms x;
        fs = str2sym(func_str);    % Función original
        dfs = diff(fs, x);         % Primera derivada f'(x)
        ddfs = diff(dfs, x);       % Segunda derivada f''(x)
        
        % Convertir a funciones numéricas para que sea rápido
        f = matlabFunction(fs);
        df = matlabFunction(dfs);
        ddf = matlabFunction(ddfs);
        
        % 3. Inicializar variables para el ciclo
        iter = 0;
        ea = 100;
        xi = x0;
        resultados = [];

        % 4. CICLO DE NEWTON-RAPHSON MODIFICADO
        while ea > es && iter < max_iter
            iter = iter + 1;
            
            % Evaluar valores actuales
            f_val = f(xi);
            df_val = df(xi);
            ddf_val = ddf(xi);
            
            % Aplicar fórmula de NR Modificado
            % Denominador: [f'(x)]^2 - f(x)*f''(x)
            denominador = (df_val^2) - (f_val * ddf_val);
            
            if denominador == 0
                error('El denominador se hizo cero. Intenta con otro x0.');
            end
            
            xi_next = xi - (f_val * df_val) / denominador;
            
            % Calcular error
            if xi_next ~= 0
                ea = abs((xi_next - xi) / xi_next) * 100;
            end
            
            % Guardar datos: [Iter, xi, f(xi), f'(xi), f''(xi), Error]
            resultados(iter, :) = [iter, xi, f_val, df_val, ddf_val, ea];
            
            % Actualizar para la siguiente vuelta
            xi = xi_next;
        end

        % 5. MOSTRAR EN TABLA
        app.UITable.Data = num2cell(resultados);
        app.UITable.ColumnName = {'Iter', 'xi', 'f(xi)', 'f''(xi)', 'f''''(xi)', 'Error %'};

        % 6. GRAFICAR
        cla(app.UIGrafica);
        hold(app.UIGrafica, 'on');
        
        % Graficar la función original
        fplot(app.UIGrafica, f, [xi-5, xi+5], 'LineWidth', 1.5);
        
        % Línea de referencia en cero
        yline(app.UIGrafica, 0, '--g', 'HandleVisibility', 'off');
        
        % Punto de la raíz encontrada
        plot(app.UIGrafica, xi, f(xi), 'ro', 'MarkerSize', 10, 'LineWidth', 2, 'MarkerFaceColor', 'r');
        
        legend(app.UIGrafica, 'f(x)', 'Raíz NR Mod');
        grid(app.UIGrafica, 'on');
        hold(app.UIGrafica, 'off');

    catch ME
        uialert(app.UIFigure, ['Error en el cálculo: ' ME.message], 'Error');
	end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIGrafica = uiaxes(app.UIFigure);
            title(app.UIGrafica, 'Gráfica')
            xlabel(app.UIGrafica, 'X')
            ylabel(app.UIGrafica, 'Y')
            zlabel(app.UIGrafica, 'Z')
            app.METODONEWTONRAPHSONMODIFICADOLabel = uilabel(app.UIFigure);
            app.METODONEWTONRAPHSONMODIFICADOLabel.Text = 'METODO NEWTON - RAPHSON MODIFICADO';
            app.IngreselafrmulaEditFieldLabel = uilabel(app.UIFigure);
            app.IngreselafrmulaEditFieldLabel.Text = 'Ingrese la fórmula:';
            app.txtFormulaIngresada = uieditfield(app.UIFigure, 'text');
            app.txtFormulaIngresada.Value = '(x-3)^2*(x+1)';
            app.btnCalcular = uibutton(app.UIFigure, 'push');
            app.btnCalcular.ButtonPushedFcn = createCallbackFcn(app, @btnCalcularButtonPushed, true);
            app.btnCalcular.Text = 'CALCULAR';
            app.VolveralmenButton = uibutton(app.UIFigure, 'push');
            app.VolveralmenButton.ButtonPushedFcn = createCallbackFcn(app, @VolveralmenButtonPushed, true);
            app.VolveralmenButton.Text = 'Volver al menú';
            app.X0EditFieldLabel = uilabel(app.UIFigure);
            app.X0EditFieldLabel.Text = 'X0';
            app.txtx0 = uieditfield(app.UIFigure, 'numeric');
            app.txtx0.ValueDisplayFormat = '%.2f';
            app.txtx0.Value = 4;
            app.ErrorestimadoEditFieldLabel = uilabel(app.UIFigure);
            app.ErrorestimadoEditFieldLabel.Text = 'Error estimado:';
            app.txtErrorEstimado = uieditfield(app.UIFigure, 'numeric');
            app.txtErrorEstimado.Value = 0.001;
            app.NoiteracionesLabel = uilabel(app.UIFigure);
            app.NoiteracionesLabel.Text = 'Max. iteraciones';
            app.txtIteraciones = uieditfield(app.UIFigure, 'numeric');
            app.txtIteraciones.ValueDisplayFormat = '%.0f';
            app.txtIteraciones.Value = 10;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Iter'; 'xi'; 'f(xi)'; 'f''''(xi)'; 'f''''''''(xi)'; 'Error %'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NRMODIFICADO

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end