classdef PUNTOFIJO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UITable                        matlab.ui.control.Table
        txtx0                          matlab.ui.control.NumericEditField
        X0EditFieldLabel               matlab.ui.control.Label
        txtNoIteraciones               matlab.ui.control.NumericEditField
        NoIteracionesEditFieldLabel    matlab.ui.control.Label
        txtErrorEstimado               matlab.ui.control.NumericEditField
        ErrorestimadoEditFieldLabel    matlab.ui.control.Label
        txtFormulaIngresada            matlab.ui.control.EditField
        IngreselafrmulaEditFieldLabel  matlab.ui.control.Label
        btnCalcular                    matlab.ui.control.Button
        VolveralmenButton              matlab.ui.control.Button
        METODODEPUNTOFIJOLabel         matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnCalcular
        function btnCalcularButtonPushed(app, event)
            % 1. Obtener datos de la UI
    g_str = app.txtFormulaIngresada.Value; % El usuario ingresa g(x)
    x0 = app.txtx0.Value;          % Valor inicial
    es = app.txtErrorEstimado.Value;
    max_iter = app.txtNoIteraciones.Value;

    try
        % 2. Convertir string a función numérica
        g = str2func(['@(x)' g_str]);
        
        % Preparar variables
        iter = 0;
        ea = 100;
        xi = x0;
        resultados = [];

        % 3. CICLO DE PUNTO FIJO
        while ea > es && iter < max_iter
            iter = iter + 1;
            
            % Fórmula: x_nueva = g(x_vieja)
            x_next = g(xi);
            
            % Calcular error
            if x_next ~= 0
                ea = abs((x_next - xi) / x_next) * 100;
            end
            
            % Guardar: [Iteración, xi, g(xi), ea]
            resultados(iter, 1) = iter;
            resultados(iter, 2) = xi;
            resultados(iter, 3) = x_next;
            resultados(iter, 4) = ea;
            
            % Actualizar para la siguiente iteración
            xi = x_next;
        end

        % 4. MOSTRAR EN TABLA
        app.UITable.Data = num2cell(resultados);
        app.UITable.ColumnName = {'Iter', 'xi', 'g(xi)', 'Error %'};

        % 5. GRAFICAR
        cla(app.UIAxes);
        hold(app.UIAxes, 'on');
        
        % Graficamos g(x) y la recta y = x (la intersección es la raíz)
        fplot(app.UIAxes, g, [xi-5, xi+5], 'LineWidth', 1.5); 
        fplot(app.UIAxes, @(x) x, [xi-5, xi+5], '--k'); % Recta y = x
        
        grid(app.UIAxes, 'on');
        % Marcar el punto de la raíz
        plot(app.UIAxes, xi, xi, 'ro', 'MarkerSize', 10, 'LineWidth', 2);
        
        legend(app.UIAxes, 'g(x)', 'y = x', 'Raíz');
        hold(app.UIAxes, 'off');

    catch ME
        uialert(app.UIFigure, ['El método divergió o hay error en g(x): ' ME.message], 'Error');
    end
        end

        % Button pushed function: VolveralmenButton
        function VolveralmenButtonPushed(app, event)
            MenuPrincipal;
            close(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Gráfica')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.METODODEPUNTOFIJOLabel = uilabel(app.UIFigure);
            app.METODODEPUNTOFIJOLabel.Text = 'METODO DE PUNTO FIJO';
            app.VolveralmenButton = uibutton(app.UIFigure, 'push');
            app.VolveralmenButton.ButtonPushedFcn = createCallbackFcn(app, @VolveralmenButtonPushed, true);
            app.VolveralmenButton.Text = 'Volver al menú';
            app.btnCalcular = uibutton(app.UIFigure, 'push');
            app.btnCalcular.ButtonPushedFcn = createCallbackFcn(app, @btnCalcularButtonPushed, true);
            app.btnCalcular.Text = 'CALCULAR';
            app.IngreselafrmulaEditFieldLabel = uilabel(app.UIFigure);
            app.IngreselafrmulaEditFieldLabel.Text = 'Ingrese la fórmula:';
            app.txtFormulaIngresada = uieditfield(app.UIFigure, 'text');
            app.txtFormulaIngresada.Value = 'sqrt(x+2)';
            app.ErrorestimadoEditFieldLabel = uilabel(app.UIFigure);
            app.ErrorestimadoEditFieldLabel.Text = 'Error estimado:';
            app.txtErrorEstimado = uieditfield(app.UIFigure, 'numeric');
            app.txtErrorEstimado.Value = 0.01;
            app.NoIteracionesEditFieldLabel = uilabel(app.UIFigure);
            app.NoIteracionesEditFieldLabel.Text = 'No. Iteraciones:';
            app.txtNoIteraciones = uieditfield(app.UIFigure, 'numeric');
            app.txtNoIteraciones.Value = 10;
            app.X0EditFieldLabel = uilabel(app.UIFigure);
            app.X0EditFieldLabel.Text = 'X0';
            app.txtx0 = uieditfield(app.UIFigure, 'numeric');
            app.txtx0.ValueDisplayFormat = '%.0f';
            app.txtx0.Value = 1;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Iter'; 'xi'; 'g(xi)'; 'Error %'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PUNTOFIJO

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end